classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ContinousTimeLabel_2            matlab.ui.control.Label
        SinusoidalWaveLabel             matlab.ui.control.Label
        FSEditFieldLabel                matlab.ui.control.Label
        FSEditField                     matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        CosineWaveLabel                 matlab.ui.control.Label
        FSEditField_2Label              matlab.ui.control.Label
        FSEditField_2                   matlab.ui.control.NumericEditField
        GenerateButton                  matlab.ui.control.Button
        PhaseChangeButton               matlab.ui.control.Button
        FreqChangeButton                matlab.ui.control.Button
        AmplitudeScalingButton_4        matlab.ui.control.Button
        AmplitudeScalingButton_5        matlab.ui.control.Button
        FreqchangeButton                matlab.ui.control.Button
        PhasechangeButton               matlab.ui.control.Button
        OperationsLabel_3               matlab.ui.control.Label
        OperationsLabel_2               matlab.ui.control.Label
        ContinousTimeLabel              matlab.ui.control.Label
        triangularWaveButton            matlab.ui.control.Button
        AmplitudeScalingButton          matlab.ui.control.Button
        TimeShiftingcompButton          matlab.ui.control.Button
        TimeShiftingAdvanceButton       matlab.ui.control.Button
        TimeFoldingButton               matlab.ui.control.Button
        ShiftingScalingButton           matlab.ui.control.Button
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        TimeScalingButton               matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        AmplitudeScalingButton_6        matlab.ui.control.Button
        CreateasignalButton             matlab.ui.control.Button
        GenerateyourdesiredsignalLabel  matlab.ui.control.Label
        SelectFIleButton                matlab.ui.control.Button
        ClcClearallButton               matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        AboutusButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        RampButton                      matlab.ui.control.Button
        UnitStepButton                  matlab.ui.control.Button
        ImpulseButton                   matlab.ui.control.Button
        Slider2Label                    matlab.ui.control.Label
        Slider2                         matlab.ui.control.Slider
        DiscreteTimeLabel               matlab.ui.control.Label
        AmplitudeScalingButton_3        matlab.ui.control.Button
        TimeShiftingcompButton_3        matlab.ui.control.Button
        TimeShiftingadvButton           matlab.ui.control.Button
        AmplitudeScalingButton_10       matlab.ui.control.Button
        TimeShiftingadvButton_5         matlab.ui.control.Button
        TimeShiftingcompButton_7        matlab.ui.control.Button
        TimeShiftingButton              matlab.ui.control.Button
        AmplitudeScalingButton_11       matlab.ui.control.Button
        TimeShiftingadvButton_6         matlab.ui.control.Button
        OperationsUnitStepLabel         matlab.ui.control.Label
        OperationsImpulseLabel_2        matlab.ui.control.Label
        Slider3Label                    matlab.ui.control.Label
        Slider3                         matlab.ui.control.Slider
        RampSignalButton                matlab.ui.control.Button
        UnitStepSignalButton            matlab.ui.control.Button
        ImpulseSignalButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

   methods (Access = private)
        
        function [x] = y(t)
        x1  = t+1
x2  = -t+1
x = x1.*(-1<t & t<=0)+x2.*(0<t & t<=1);
 end
    end
    
    methods (Access = public)
        
        function r = ramp(x)
r = max(0,x)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {725, 725, 725};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {725, 725};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x', 242};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: AmplitudeScalingButton
        function AmplitudeScalingButtonPushed(app, event)
              t = -10:0.01:10;  %Time vector
                          value_amplitude = app.Slider.Value;

            y2 = value_amplitude*y(t);      %Amplitude scaling
           plot(app.UIAxes , t , y2)
           grid(app.UIAxes)
                       hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingcompButton
        function TimeShiftingcompButtonPushed(app, event)
            t = -10:0.01:10; 
                        value_amplitude = app.Slider.Value;

            y3 = y(t-value_amplitude);      %Time shifting
            plot(app.UIAxes , t , y3)
            grid(app.UIAxes)
                        hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingAdvanceButton
        function TimeShiftingAdvanceButtonPushed(app, event)
        t = -10:0.01:10;  %Time vector
                    value_amplitude = app.Slider.Value;

    y4 = y(t+value_amplitude) 
    plot(app.UIAxes , t , y4)
    grid(app.UIAxes)
                hold(app.UIAxes)


        end

        % Button pushed function: TimeScalingButton
        function TimeScalingButtonPushed(app, event)
            t = -10:0.01:10;  %Time vector 
            value_amplitude = app.Slider.Value;
        y5 = y(value_amplitude*t);      %Time scaling  ( Com[ression)
        
       plot(app.UIAxes , t , y5)
                   grid(app.UIAxes)
                               hold(app.UIAxes)


        end

        % Button pushed function: TimeFoldingButton
        function TimeFoldingButtonPushed(app, event)
            t = -10:0.01:10;  %Time vector
                        value_amplitude = app.Slider.Value;

            y7 = y(value_amplitude*t);     %Time folding
            plot(app.UIAxes , t , y7)
                        grid(app.UIAxes)
                                    hold(app.UIAxes)


        end

        % Button pushed function: ShiftingScalingButton
        function ShiftingScalingButtonPushed(app, event)
             t = -10:0.01:10;  %Time vector
                         value_amplitude = app.Slider.Value;

         y8 = y(value_amplitude*t -value_amplitude);   %Shiftings and scaling
         plot(app.UIAxes , t , y8)
                     grid(app.UIAxes)
                                 hold(app.UIAxes)


        end

        % Button pushed function: triangularWaveButton
        function triangularWaveButtonPushed(app, event)
            t = -10:0.01:10;  %Time vector
                        value_amplitude = app.Slider.Value;

            y1  = y(t);       %Orignal Signal
            plot(app.UIAxes , t , y1)
            hold(app.UIAxes)
            grid(app.UIAxes)

        end

        % Callback function
        function SquareWaveButton_2Pushed(app, event)
           s=0:.001:1;
f=3;
sq=10*0.5*(square(2*pi*f*s)+1);
plot(app.UIAxes , s , sq)   
            hold(app.UIAxes)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Callback function
        function AmplitudeScalingButton_2Pushed(app, event)
            s=0:.001:1;
f=3;
sq=10*0.5*(square(2*pi*f*s)+1);
sq2 = 2*sq
plot(app.UIAxes , s , sq2)
            hold(app.UIAxes)

        end

        % Callback function
        function ClearallButtonPushed(app, event)
            clear all;
        end

        % Callback function
        function TimeShiftingcompButton_2Pushed(app, event)
             s=-10:10;
f=3;
sq=10*0.5*(square(2*pi*f*s)+1);
sq3 = sq(s-2)
plot(app.UIAxes , s , sq2)
            hold(app.UIAxes)

        end

        % Callback function
        function TimeShiftingadvButtonPushed(app, event)
            s=10:.01:20 ;  %Time vector
  value_amplitude = app.Slider.Value;
f=2;
l=1000*0.5*(square(2*pi*f*s)+1);
            l3 = value_amplitude(s-2);      %Amplitude scaling
            plot(app.UIAxes , s, l3)
            hold(app.UIAxes)
        end

        % Callback function
        function TimeScalingButton_2Pushed(app, event)
            s=0:.001:1;
f=3;
sq=10*0.5*(square(2*pi*f*s)+1);
sq2 = sq(2*sq)
plot(app.UIAxes , s , sq2)  
            hold(app.UIAxes)

        end

        % Callback function
        function TimeFoldingButton_2Pushed(app, event)
            s=10:.01:20 ;  %Time vector
  value_amplitude = app.Slider.Value;
f=2;
l=1000*0.5*(square(2*pi*f*s)+1);
            l5 = value_amplitude(-1*s);      %Amplitude scaling
            plot(app.UIAxes , s, l5)
            hold(app.UIAxes)
        end

        % Callback function
        function ShiftingScalingButton_2Pushed(app, event)
            s=10:.01:20 ;  %Time vector
  value_amplitude = app.Slider.Value;
f=2;
l=1000*0.5*(square(2*pi*f*s)+1);
            l6 = value_amplitude(2*s-3);      %Amplitude scaling
            plot(app.UIAxes , s, l1)
            hold(app.UIAxes)
        end

        % Button pushed function: SelectFIleButton
        function SelectFIleButtonPushed(app, event)
            [file,path] = uigetfile('*.m');
if isequal(file,0)
   disp('User selected Cancel');
else
   disp(['User selected ', fullfile(path,file)]);
end
        end

        % Callback function
        function dtEditFieldValueChanged(app, event)
            value = app.dtEditField.Value;
                                      dt = app.dtEditField.Value;


        end

        % Callback function
        function DurationEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function dt2EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function DTsignalButtonPushed(app, event)
         value = app.nEditField.Value;
            n = app.nEditField;
f_one = 2*0.5.^n.* heaviside(n+2)
stem(app.UIAxes , f_one , n)
        end

        % Callback function
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            n_value = app.LowerLimitEditField.Value;
            
        end

        % Callback function
        function HigherLimitEditFieldValueChanged(app, event)
            value = app.HigherLimitEditField.Value;
            n_value2 = app.HigherLimitEditField.Value;
        end

        % Callback function
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            n_n2 = app.nEditField;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.FSEditField.Value;
                        n_n2 = app.nEditField;

            
        end

        % Button pushed function: RampButton
        function RampButtonPushed(app, event)
            value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
stem(app.UIAxes , n,ramp)
            hold(app.UIAxes)


        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            dt_1 =   app.Slider2.Value;
        end

        % Button pushed function: UnitStepButton
        function UnitStepButtonPushed(app, event)
        value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
       
n=n_1:n_2; % specify index n
step_sig=(n>=0); % define the unit step sequence
stem(app.UIAxes , n, step_sig ) % plot the unit step sequence
            hold(app.UIAxes)


        end

        % Button pushed function: ImpulseButton
        function ImpulseButtonPushed(app, event)
          value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
           
        n=n_1:n_2; % specify index n
delta=(n==0); % define the delta sequence
stem(app.UIAxes  , n,delta) % plot the delta sequence
            hold(app.UIAxes)


        end

        % Value changed function: FSEditField
        function FSEditFieldValueChanged(app, event)
            value = app.FSEditField.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=sin(2*pi*50*t); % signal vector
plot(app.UIAxes , t,x);
            hold(app.UIAxes)

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
           value = app.FSEditField.Value;
            Fs1 = app.FSEditField_2.Value;  
Ts=1/Fs1; % sampling interval
t=0:Ts:0.1; % sampling instants
x=cos(2*pi*50*t); % signal vector
plot(app.UIAxes , t,x);
            hold(app.UIAxes)


        end

        % Button pushed function: RampSignalButton
        function RampSignalButtonPushed(app, event)
      value = app.Slider.Value;
            n_1 = app.Slider.Value; 
        dt = .001;
duration = n_1;
t4 = -1: dt : 0-dt;
t5 = 0:dt:duration-dt;
t6 = [t4 , t5];
x4=[zeros(1 , length(t4)) t5];
plot(app.UIAxes , t6 , x4 )
            hold(app.UIAxes)


        end

        % Button pushed function: UnitStepSignalButton
        function UnitStepSignalButtonPushed(app, event)
         value = app.Slider.Value;
            n_1 = app.Slider.Value; 
        dt = .001;
duration =n_1;
t7 = -1: dt : 0-dt;
t8 = 0:dt:duration-dt;
t9 = [t7 , t8];
x5=[zeros(1 , length(t7)) ones(1 , length(t8))];
plot(app.UIAxes , t9,x5)
            hold(app.UIAxes)


        end

        % Button pushed function: ImpulseSignalButton
        function ImpulseSignalButtonPushed(app, event)
            duration = 2;
dt = 0.001
t10 = -1*duration:dt:duration-dt;
impulse = t10==0;
plot(app.UIAxes , t10 , impulse)
            hold(app.UIAxes)

 
        end

        % Button pushed function: AmplitudeScalingButton_4
        function AmplitudeScalingButton_4Pushed(app, event)
         value = app.Slider.Value;
            n = app.Slider2.Value;     
        value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=sin(2*pi*50*t); % signal vector
x3 = n*x
plot(app.UIAxes , t,x3);
            hold(app.UIAxes)

        end

        % Button pushed function: FreqChangeButton
        function FreqChangeButtonPushed(app, event)
             value = app.Slider.Value;
            n = app.Slider2.Value;  
            n2 = app.Slider2.Value;
        value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=sin(2*pi*n2*t); % signal vector
plot(app.UIAxes , t,x);
            hold(app.UIAxes)

        end

        % Button pushed function: PhaseChangeButton
        function PhaseChangeButtonPushed(app, event)
            value = app.Slider.Value;
            phase1 = app.Slider2.Value;  
            
        value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=sin(2*pi*50*t+phase1); % signal vector
plot(app.UIAxes , t,x);
            hold(app.UIAxes)

        
        end

        % Value changed function: FSEditField_2
        function FSEditField_2ValueChanged(app, event)
            value = app.FSEditField_2.Value;
            Fs1 = app.FSEditField_2.Value;
        end

        % Button pushed function: AmplitudeScalingButton_5
        function AmplitudeScalingButton_5Pushed(app, event)
              value = app.Slider2.Value;
            n = app.Slider2.Value;     
        value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=cos(2*pi*50*t); % signal vector
x11 = n*x
plot(app.UIAxes , t ,x11);
            hold(app.UIAxes)


        end

        % Button pushed function: FreqchangeButton
        function FreqchangeButtonPushed(app, event)
            value = app.Slider2.Value;
            n = app.Slider2.Value;     
        value = app.FSEditField.Value;
            Fs = app.FSEditField.Value;
           
Ts=1/Fs; % sampling interval
t=0:Ts:0.1; % sampling instants
x=cos(2*pi*n*t); % signal vector
plot(app.UIAxes , t ,x);
            hold(app.UIAxes)

        end

        % Button pushed function: CreateasignalButton
        function CreateasignalButtonPushed(app, event)
     
                        Value = app.Slider.Value;
            n1 = app.Slider.Value : -2;
            n2 = app.Slider2.Value : 2;
            n3 = app.Slider3.Value : 10;
            n = [ n1 n2 n3];
            t = length(n)
            x = [ ones(1,length(n1)) 2*ones(1 , length(n2)) zeros(1 ,length(n3))]
            plot(app.UIAxes , n , x )
                        hold(app.UIAxes)

            
        end

        % Button pushed function: ClcClearallButton
        function ClcClearallButtonPushed(app, event)
            
        end

        % Button pushed function: AmplitudeScalingButton_6
        function AmplitudeScalingButton_6Pushed(app, event)
               Value = app.Slider.Value;
            n1 = app.Slider.Value : -2;
            n2 = app.Slider2.Value : 2;
            n3 = app.Slider3.Value : 10;
            n = [ n1 n2 n3];
            t = length(n)
            x = [ ones(1,length(n1)) 2*ones(1 , length(n2)) zeros(1 ,length(n3))]
            x2 = 2*x
            plot(app.UIAxes , n , x2 )
                        hold(app.UIAxes)

            
        end

        % Callback function
        function TimeShiftingcompButton_4Pushed(app, event)
         Value = app.Slider.Value;
            n1 = app.Slider.Value : -2;
            n2 = app.Slider2.Value : 2;
            n3 = app.Slider3.Value : 10;
            n = [ n1 n2 n3];
            t = length(n)
            x = [ ones(1,length(n1)) 2*ones(1 , length(n2)) zeros(1 ,length(n3))]   
        x5 = x(n1*x)
                    plot(app.UIAxes , n , x5 )
                                hold(app.UIAxes)


        end

        % Callback function
        function AmplitudeScalingButton_7Pushed2(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value; 
            n = app.Slider2.Value
        dt = .001;
duration = n_1;
t4 = -1: dt : 0-dt;
t5 = 0:dt:duration-dt;
t6 = [t4 , t5];
x4=[zeros(1 , length(t4)) t5];
x5 =n*x4
plot(app.UIAxes , t6 , x5 )
            hold(app.UIAxes)


        end

        % Callback function
        function AmplitudeScalingButton_8Pushed(app, event)
              t = -10:0.01:10;  %Time vector
                          value_amplitude = app.Slider.Value;

            y2 = value_amplitude*y(t);      %Amplitude scaling
           plot(app.UIAxes , t , y2)
           grid(app.UIAxes)
                       hold(app.UIAxes)


        end

        % Callback function
        function TimeShiftingcompButton_5Pushed(app, event)
             t = -10:0.01:10; 
                        value_amplitude = app.Slider.Value;

            y3 = y(t-value_amplitude);      %Time shifting
            plot(app.UIAxes , t , y3)
            grid(app.UIAxes)
                        hold(app.UIAxes)


        end

        % Callback function
        function TimeShiftingcompButton_2Pushed2(app, event)
            value = app.Slider.Value;
            n_1 = app.Slider.Value; 
            n = app.Slider2.Value
        dt = .001;
duration = n_1;
t4 = -1: dt : 0-dt;
t5 = 0:dt:duration-dt;
t6 = [t4 , t5];
x4=[zeros(1 , length(t4)) t5];
x5 = x4(t6-n)
plot(app.UIAxes , t6 , x5 )
            hold(app.UIAxes)

        end

        % Button pushed function: AmplitudeScalingButton_3
        function AmplitudeScalingButton_3Pushed(app, event)
              value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
            n1 = app.Slider2.Value
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
n2 = n1*ramp

stem(app.UIAxes , n2,ramp)
            hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingcompButton_3
        function TimeShiftingcompButton_3Pushed(app, event)
              value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
              n1 = app.Slider2.Value
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
n2 = n1*ramp-n
stem(app.UIAxes , n2,ramp)
            hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingadvButton
        function TimeShiftingadvButtonPushed2(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
              n1 = app.Slider2.Value
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
n2 = n1*ramp+n
stem(app.UIAxes , n2,ramp)
            hold(app.UIAxes)

        end

        % Callback function
        function TimeScalingButton_3Pushed(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
              n1 = app.Slider2.Value
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
n2 = n1*(n*ramp)
stem(app.UIAxes , n2,ramp)
            hold(app.UIAxes)

        end

        % Callback function
        function TimeFoldingButton_3Pushed(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
              n1 = app.Slider2.Value
 n=n_1:n_2; % define index n
ramp=n.*(n>=0); % define a ramp
n2 = n1*(-n*ramp)
stem(app.UIAxes , n2,ramp)
            hold(app.UIAxes)

        end

        % Button pushed function: AmplitudeScalingButton_10
        function AmplitudeScalingButton_10Pushed(app, event)
            value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
       
n=n_1:n_2; % specify index n
step_sig=(n>=0); % define the unit step sequence
x2 = n_1*step_sig
stem(app.UIAxes , n, x2 ) % plot the unit step sequence
            hold(app.UIAxes)

        end

        % Button pushed function: TimeShiftingcompButton_7
        function TimeShiftingcompButton_7Pushed(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
       
n=n_1:n_2; % specify index n
step_sig=(n>=0); % define the unit step sequence
x2 = step_sig(step_sig-n_1)
stem(app.UIAxes , n, x2 ) % plot the unit step sequence
            hold(app.UIAxes)

        end

        % Button pushed function: TimeShiftingadvButton_5
        function TimeShiftingadvButton_5Pushed(app, event)
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
       
n=n_1:n_2; % specify index n
step_sig=(n>=0); % define the unit step sequence
x2 = n(step_sig+n_1)
stem(app.UIAxes , n, x2 ) % plot the unit step sequence
            hold(app.UIAxes)

        end

        % Button pushed function: AmplitudeScalingButton_11
        function AmplitudeScalingButton_11Pushed(app, event)
            value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
           
        n=n_1:n_2; % specify index n
delta=(n==0); % define the delta sequence
x1 = delta*n_1
stem(app.UIAxes  , n,x1) % plot the delta sequence
            hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingButton
        function TimeShiftingButtonPushed(app, event)
            value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
           
        n=n_1:n_2; % specify index n
delta=(n==0); % define the delta sequence
n2 = n_1*delta+n

stem(app.UIAxes  , n,n2) % plot the delta sequence
            hold(app.UIAxes)


        end

        % Button pushed function: TimeShiftingadvButton_6
        function TimeShiftingadvButton_6Pushed(app, event)
             
             value = app.Slider.Value;
            n_1 = app.Slider.Value;
            value = app.Slider2.Value;
            n_2 = app.Slider2.Value;
           
        n=n_1:n_2; % specify index n
delta=(n==0); % define the delta sequence
             x2 = n(delta+n_1)

stem(app.UIAxes  , n,x2) % plot the delta sequence
            hold(app.UIAxes)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            winopen('help.pdf')
        end

        % Button pushed function: AboutusButton
        function AboutusButtonPushed(app, event)
                        winopen('Aboutus.pdf')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ContinousTimeLabel_2 = uilabel(app.LeftPanel);
            app.ContinousTimeLabel_2.Text = 'Continous Time';
            app.SinusoidalWaveLabel = uilabel(app.LeftPanel);
            app.SinusoidalWaveLabel.Text = 'Sinusoidal Wave';
            app.FSEditFieldLabel = uilabel(app.LeftPanel);
            app.FSEditFieldLabel.Text = 'FS';
            app.FSEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FSEditField.ValueChangedFcn = createCallbackFcn(app, @FSEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CosineWaveLabel = uilabel(app.LeftPanel);
            app.CosineWaveLabel.Text = 'Cosine Wave';
            app.FSEditField_2Label = uilabel(app.LeftPanel);
            app.FSEditField_2Label.Text = 'FS';
            app.FSEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.FSEditField_2.ValueChangedFcn = createCallbackFcn(app, @FSEditField_2ValueChanged, true);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.PhaseChangeButton = uibutton(app.LeftPanel, 'push');
            app.PhaseChangeButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseChangeButtonPushed, true);
            app.PhaseChangeButton.Text = 'Phase Change';
            app.FreqChangeButton = uibutton(app.LeftPanel, 'push');
            app.FreqChangeButton.ButtonPushedFcn = createCallbackFcn(app, @FreqChangeButtonPushed, true);
            app.FreqChangeButton.Text = 'Freq Change';
            app.AmplitudeScalingButton_4 = uibutton(app.LeftPanel, 'push');
            app.AmplitudeScalingButton_4.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_4Pushed, true);
            app.AmplitudeScalingButton_4.Text = 'Amplitude Scaling';
            app.AmplitudeScalingButton_5 = uibutton(app.LeftPanel, 'push');
            app.AmplitudeScalingButton_5.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_5Pushed, true);
            app.AmplitudeScalingButton_5.Text = 'Amplitude Scaling';
            app.FreqchangeButton = uibutton(app.LeftPanel, 'push');
            app.FreqchangeButton.ButtonPushedFcn = createCallbackFcn(app, @FreqchangeButtonPushed, true);
            app.FreqchangeButton.Text = 'Freq.change';
            app.PhasechangeButton = uibutton(app.LeftPanel, 'push');
            app.PhasechangeButton.Text = 'Phase change';
            app.OperationsLabel_3 = uilabel(app.LeftPanel);
            app.OperationsLabel_3.Text = 'Operations';
            app.OperationsLabel_2 = uilabel(app.LeftPanel);
            app.OperationsLabel_2.Text = 'Operations';
            app.ContinousTimeLabel = uilabel(app.LeftPanel);
            app.ContinousTimeLabel.Text = 'Continous Time';
            app.triangularWaveButton = uibutton(app.LeftPanel, 'push');
            app.triangularWaveButton.ButtonPushedFcn = createCallbackFcn(app, @triangularWaveButtonPushed, true);
            app.triangularWaveButton.Text = 'triangular Wave';
            app.AmplitudeScalingButton = uibutton(app.LeftPanel, 'push');
            app.AmplitudeScalingButton.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButtonPushed, true);
            app.AmplitudeScalingButton.Text = 'Amplitude Scaling';
            app.TimeShiftingcompButton = uibutton(app.LeftPanel, 'push');
            app.TimeShiftingcompButton.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingcompButtonPushed, true);
            app.TimeShiftingcompButton.Text = 'Time Shifting(comp.)';
            app.TimeShiftingAdvanceButton = uibutton(app.LeftPanel, 'push');
            app.TimeShiftingAdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingAdvanceButtonPushed, true);
            app.TimeShiftingAdvanceButton.Text = 'Time Shifting Advance';
            app.TimeFoldingButton = uibutton(app.LeftPanel, 'push');
            app.TimeFoldingButton.ButtonPushedFcn = createCallbackFcn(app, @TimeFoldingButtonPushed, true);
            app.TimeFoldingButton.Text = 'Time Folding';
            app.ShiftingScalingButton = uibutton(app.LeftPanel, 'push');
            app.ShiftingScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftingScalingButtonPushed, true);
            app.ShiftingScalingButton.Text = 'Shifting & Scaling';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [-10 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeScalingButton = uibutton(app.LeftPanel, 'push');
            app.TimeScalingButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScalingButtonPushed, true);
            app.TimeScalingButton.Text = 'Time Scaling';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Signal Operations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeScalingButton_6 = uibutton(app.CenterPanel, 'push');
            app.AmplitudeScalingButton_6.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_6Pushed, true);
            app.AmplitudeScalingButton_6.Text = 'Amplitude Scaling';
            app.CreateasignalButton = uibutton(app.CenterPanel, 'push');
            app.CreateasignalButton.ButtonPushedFcn = createCallbackFcn(app, @CreateasignalButtonPushed, true);
            app.CreateasignalButton.Text = 'Create a signal';
            app.GenerateyourdesiredsignalLabel = uilabel(app.CenterPanel);
            app.GenerateyourdesiredsignalLabel.Text = 'Generate your desired signal';
            app.SelectFIleButton = uibutton(app.CenterPanel, 'push');
            app.SelectFIleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFIleButtonPushed, true);
            app.SelectFIleButton.Text = 'Select FIle';
            app.ClcClearallButton = uibutton(app.CenterPanel, 'push');
            app.ClcClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClcClearallButtonPushed, true);
            app.ClcClearallButton.Text = 'Clc/Clear all';
            app.HelpButton = uibutton(app.CenterPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AboutusButton = uibutton(app.CenterPanel, 'push');
            app.AboutusButton.ButtonPushedFcn = createCallbackFcn(app, @AboutusButtonPushed, true);
            app.AboutusButton.Text = 'About us';
            app.RightPanel = uipanel(app.GridLayout);
            app.RampButton = uibutton(app.RightPanel, 'push');
            app.RampButton.ButtonPushedFcn = createCallbackFcn(app, @RampButtonPushed, true);
            app.RampButton.Text = 'Ramp';
            app.UnitStepButton = uibutton(app.RightPanel, 'push');
            app.UnitStepButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepButtonPushed, true);
            app.UnitStepButton.Text = 'Unit Step';
            app.ImpulseButton = uibutton(app.RightPanel, 'push');
            app.ImpulseButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseButtonPushed, true);
            app.ImpulseButton.Text = 'Impulse';
            app.Slider2Label = uilabel(app.RightPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.RightPanel);
            app.Slider2.Limits = [-10 10];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.DiscreteTimeLabel = uilabel(app.RightPanel);
            app.DiscreteTimeLabel.Text = 'Discrete Time';
            app.AmplitudeScalingButton_3 = uibutton(app.RightPanel, 'push');
            app.AmplitudeScalingButton_3.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_3Pushed, true);
            app.AmplitudeScalingButton_3.Text = 'Amplitude Scaling';
            app.TimeShiftingcompButton_3 = uibutton(app.RightPanel, 'push');
            app.TimeShiftingcompButton_3.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingcompButton_3Pushed, true);
            app.TimeShiftingcompButton_3.Text = 'Time Shifting(comp.)';
            app.TimeShiftingadvButton = uibutton(app.RightPanel, 'push');
            app.TimeShiftingadvButton.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingadvButtonPushed2, true);
            app.TimeShiftingadvButton.Text = 'Time Shifting(adv.)';
            app.AmplitudeScalingButton_10 = uibutton(app.RightPanel, 'push');
            app.AmplitudeScalingButton_10.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_10Pushed, true);
            app.AmplitudeScalingButton_10.Text = 'Amplitude Scaling';
            app.TimeShiftingadvButton_5 = uibutton(app.RightPanel, 'push');
            app.TimeShiftingadvButton_5.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingadvButton_5Pushed, true);
            app.TimeShiftingadvButton_5.Text = 'Time Shifting(adv.)';
            app.TimeShiftingcompButton_7 = uibutton(app.RightPanel, 'push');
            app.TimeShiftingcompButton_7.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingcompButton_7Pushed, true);
            app.TimeShiftingcompButton_7.Text = 'Time Shifting(comp.)';
            app.TimeShiftingButton = uibutton(app.RightPanel, 'push');
            app.TimeShiftingButton.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingButtonPushed, true);
            app.TimeShiftingButton.Text = 'Time Shifting';
            app.AmplitudeScalingButton_11 = uibutton(app.RightPanel, 'push');
            app.AmplitudeScalingButton_11.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeScalingButton_11Pushed, true);
            app.AmplitudeScalingButton_11.Text = 'Amplitude Scaling';
            app.TimeShiftingadvButton_6 = uibutton(app.RightPanel, 'push');
            app.TimeShiftingadvButton_6.ButtonPushedFcn = createCallbackFcn(app, @TimeShiftingadvButton_6Pushed, true);
            app.TimeShiftingadvButton_6.Text = 'Time Shifting(adv.)';
            app.OperationsUnitStepLabel = uilabel(app.RightPanel);
            app.OperationsUnitStepLabel.Text = 'Operations(Unit Step)';
            app.OperationsImpulseLabel_2 = uilabel(app.RightPanel);
            app.OperationsImpulseLabel_2.Text = 'Operations(Impulse)';
            app.Slider3Label = uilabel(app.RightPanel);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.RightPanel);
            app.Slider3.Limits = [-10 10];
            app.RampSignalButton = uibutton(app.RightPanel, 'push');
            app.RampSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RampSignalButtonPushed, true);
            app.RampSignalButton.Text = 'Ramp Signal';
            app.UnitStepSignalButton = uibutton(app.RightPanel, 'push');
            app.UnitStepSignalButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepSignalButtonPushed, true);
            app.UnitStepSignalButton.Text = 'Unit Step Signal';
            app.ImpulseSignalButton = uibutton(app.RightPanel, 'push');
            app.ImpulseSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseSignalButtonPushed, true);
            app.ImpulseSignalButton.Text = 'Impulse Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end